classdef imgdistviewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageDistanceViewer  matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        OpenMenuItem         matlab.ui.container.Menu
        QuitMenuItem         matlab.ui.container.Menu
        EditMenu             matlab.ui.container.Menu
        CutMenuItem          matlab.ui.container.Menu
        CopyMenuItem         matlab.ui.container.Menu
        PasteMenuItem        matlab.ui.container.Menu
        HelpMenu             matlab.ui.container.Menu
        RandomDataMenu       matlab.ui.container.Menu
        GotoGitMenuItem      matlab.ui.container.Menu
        DataTable            matlab.ui.control.Table
        ZoomSliderLabel      matlab.ui.control.Label
        ZoomSlider           matlab.ui.control.Slider
        ZoomAxis             matlab.ui.control.UIAxes
        ImageAxis            matlab.ui.control.UIAxes
        DataAxis             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data
        handles
        selected
    end
    
    methods (Access = private)
        function refreshDataDisplay(app)
            % data table
            tabledata(:,1) = num2cell(1:length(app.data.x));
            tabledata(:,2) = app.data.imName;
            tabledata(:,3) = num2cell(app.data.x);
            tabledata(:,4) = num2cell(app.data.y);
            tabledata(:,5) = num2cell(app.data.s);
            app.DataTable.Data = cell2table(tabledata);
            
            % data axis
            cla(app.DataAxis)
            hold(app.DataAxis,'on')
            for ii=1:length(app.data.x)
                im = app.data.ims{ii};
                x = app.data.x(ii);
                y = app.data.y(ii);
                s = app.data.s(ii);
                
                app.handles.im(ii) = imshow(im,'parent',app.DataAxis,'XData',[x-s/2 x+s/2],'YData',[y-s/2 y+s/2]);
                app.handles.im(ii).ButtonDownFcn = @(a,e) axisClick(app,a,e);
                app.handles.im(ii).UserData = ii;
            end
            axis(app.DataAxis,'on'); grid(app.DataAxis,'on');
            set(app.DataAxis,'xtick',0:0.25:1,'ytick',0:0.25:1)
            set(app.DataAxis,'xlim',[0 1],'ylim',[0 1])
            set(app.DataAxis,'ydir','normal');
        end
        
        function refreshZoomDisplay(app)
            % zoom axis
            cla(app.ZoomAxis)
            hold(app.ZoomAxis,'on')
            for ii=1:length(app.data.x)
                im = app.data.ims{ii};
                x = app.data.x(ii);
                y = app.data.y(ii);
                s = app.data.s(ii);
                
                app.handles.im(ii) = imshow(im,'parent',app.ZoomAxis,'XData',[x-s/2 x+s/2],'YData',[y-s/2 y+s/2]);
            end
            axis(app.ZoomAxis,'on'); grid(app.ZoomAxis,'on');
            set(app.ZoomAxis,'xtick',0:0.1:1,'ytick',0:0.1:1)
            set(app.ZoomAxis,'ydir','normal');
            app.panZoomDisplay;
        end
        
        function panZoomDisplay(app)
            if isempty(app.selected)
                x = 0.5; y = 0.5;
            else
                x = app.data.x(app.selected);
                y = app.data.y(app.selected);
            end
            xlim = [x-app.ZoomSlider.Value/2 x+app.ZoomSlider.Value/2];
            ylim = [y-app.ZoomSlider.Value/2 y+app.ZoomSlider.Value/2];
            set(app.ZoomAxis,'xlim',xlim,'ylim',ylim)
        end
        
        function axisClick(app,o,e)
            app.selected = o.UserData;
            app.selectIm;
        end
        
        function selectIm(app)
            idx = app.selected;
            % image axis
            im = app.data.ims{idx};
            imshow(im,'parent',app.ImageAxis)
            title(app.ImageAxis,[num2str(idx) ': ' app.data.imName{idx}]);
            
            % data axis outline
            x = app.data.x(idx);
            y = app.data.y(idx);
            s = app.data.s(idx);
            if isfield(app.handles,'datamark')
                if isvalid(app.handles.datamark)
                    delete(app.handles.datamark);
                end
            end
            app.handles.datamark = plot(app.DataAxis,x,y,'Color','k',"Marker","s","LineWidth",3,'MarkerSize',s*500);
            app.panZoomDisplay;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: RandomDataMenu
        function RandomDataMenuSelected(app, event)
            n = 40;
            letters = 'a':'z';
            
            app.data.x = 0.05+0.9*rand(1,n);
            app.data.y = 0.05+0.9*rand(1,n);
            app.data.s = 0.02+0.1*rand(1,n);
            app.data.imName = arrayfun(@(x) [letters(randi(26,6,1)) '.png'],1:n,'UniformOutput',false);
            app.data.imPath = arrayfun(@(x) 'img',1:n,'UniformOutput',false);
            ims = arrayfun(@(x) reshape(conv2(rand(55,55*3),ones(5),"same")/25,55,55,3),1:n,'uniformoutput',false);
            app.data.ims = cellfun(@addborder,ims,'UniformOutput',false);
            app.refreshDataDisplay;
            app.refreshZoomDisplay;
            
            function im = addborder(im)
                idx = randi(3);
                col = rand;
                im(:,1:5,idx) = col;
                im(:,51:55,idx) = col;
                im(1:5,:,idx) = col;
                im(51:55,:,idx) = col;
            end
            
            % x = app.data.x;
            % y = app.data.y;
            % s = app.data.s;
            % imPath = app.data.imPath;
            % imName = app.data.imName;
            % for ii=1:n
            %     imwrite(app.data.ims{ii},[imPath{ii} '/' imName{ii}]);
            % end
            % save('randData.mat','x','y','s','imName','imPath')
        end

        % Cell selection callback: DataTable
        function DataTableCellSelection(app, event)
            idx = event.Indices(:,1);
            app.selected = idx;
            
            app.selectIm()
        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            app.panZoomDisplay;
        end

        % Menu selected function: OpenMenuItem
        function OpenMenuItemSelected(app, event)
            [filename, pathname] = uigetfile(".mat","Select a data file...","MultiSelect","off");
            app.data = load([pathname '/' filename]);
            
            for ii=1:length(app.data.x)
                app.data.ims{ii} = imread([app.data.imPath{ii} '/' app.data.imName{ii}]);
            end
            
            app.refreshDataDisplay;
            app.refreshZoomDisplay;
        end

        % Menu selected function: QuitMenuItem
        function QuitMenuItemSelected(app, event)
            app.delete;
        end

        % Menu selected function: GotoGitMenuItem
        function GotoGitMenuItemSelected(app, event)
            web('https://github.com/ramanujansrinath/imdistviewer')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageDistanceViewer = uifigure('Visible', 'off');
            app.ImageDistanceViewer.Name = 'Image Distance Viewer';
            app.FileMenu = uimenu(app.ImageDistanceViewer);
            app.FileMenu.Text = 'File';
            app.OpenMenuItem = uimenu(app.FileMenu);
            app.OpenMenuItem.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuItemSelected, true);
            app.OpenMenuItem.Text = 'Open...';
            app.QuitMenuItem = uimenu(app.FileMenu);
            app.QuitMenuItem.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuItemSelected, true);
            app.QuitMenuItem.Text = 'Quit';
            app.EditMenu = uimenu(app.ImageDistanceViewer);
            app.EditMenu.Text = 'Edit';
            app.CutMenuItem = uimenu(app.EditMenu);
            app.CutMenuItem.Text = 'Cut';
            app.CopyMenuItem = uimenu(app.EditMenu);
            app.CopyMenuItem.Text = 'Copy';
            app.PasteMenuItem = uimenu(app.EditMenu);
            app.PasteMenuItem.Text = 'Paste';
            app.HelpMenu = uimenu(app.ImageDistanceViewer);
            app.HelpMenu.Text = 'Help';
            app.RandomDataMenu = uimenu(app.HelpMenu);
            app.RandomDataMenu.MenuSelectedFcn = createCallbackFcn(app, @RandomDataMenuSelected, true);
            app.RandomDataMenu.Text = 'Load Random Data';
            app.GotoGitMenuItem = uimenu(app.HelpMenu);
            app.GotoGitMenuItem.MenuSelectedFcn = createCallbackFcn(app, @GotoGitMenuItemSelected, true);
            app.GotoGitMenuItem.Text = 'Go to Github Page';
            app.DataTable = uitable(app.ImageDistanceViewer);
            app.DataTable.ColumnName = {'Idx'; 'Name'; 'X'; 'Y'; 'Size'};
            app.DataTable.RowName = {};
            app.DataTable.ColumnSortable = true;
            app.DataTable.CellSelectionCallback = createCallbackFcn(app, @DataTableCellSelection, true);
            app.ZoomSliderLabel = uilabel(app.ImageDistanceViewer);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.ImageDistanceViewer);
            app.ZoomSlider.Limits = [0 1];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.Value = 0.3;
            app.ZoomAxis = uiaxes(app.ImageDistanceViewer);
            title(app.ZoomAxis, '')
            xlabel(app.ZoomAxis, '')
            ylabel(app.ZoomAxis, '')
            app.ZoomAxis.XTick = [];
            app.ZoomAxis.YTick = [];
            app.ImageAxis = uiaxes(app.ImageDistanceViewer);
            title(app.ImageAxis, '')
            xlabel(app.ImageAxis, '')
            ylabel(app.ImageAxis, '')
            app.ImageAxis.XTick = [];
            app.ImageAxis.YTick = [];
            app.DataAxis = uiaxes(app.ImageDistanceViewer);
            title(app.DataAxis, 'Image Distance')
            xlabel(app.DataAxis, 'X')
            ylabel(app.DataAxis, 'Y')
            % Show the figure after all components are created
            app.ImageDistanceViewer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgdistviewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageDistanceViewer)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageDistanceViewer)
        end
    end
end